classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        ImportImageMenu  matlab.ui.container.Menu
        DropDown         matlab.ui.control.DropDown
        DropDownLabel    matlab.ui.control.Label
        TabGroup         matlab.ui.container.TabGroup
        Tab1             matlab.ui.container.Tab
        TabGroup1        matlab.ui.container.TabGroup
        Tab11            matlab.ui.container.Tab
        linearButton     matlab.ui.control.Button
        b                matlab.ui.control.NumericEditField
        bLabel           matlab.ui.control.Label
        a                matlab.ui.control.NumericEditField
        aLabel           matlab.ui.control.Label
        linearF          matlab.ui.control.Image
        Tab12            matlab.ui.container.Tab
        logButton        matlab.ui.control.Button
        c                matlab.ui.control.NumericEditField
        cLabel           matlab.ui.control.Label
        logF             matlab.ui.control.Image
        Tab13            matlab.ui.container.Tab
        index2           matlab.ui.control.NumericEditField
        cLabel_3         matlab.ui.control.Label
        index1           matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        indexF           matlab.ui.control.Image
        indexButton      matlab.ui.control.Button
        Tab2             matlab.ui.container.Tab
        TabGroup3        matlab.ui.container.TabGroup
        Tab21            matlab.ui.container.Tab
        Tab22            matlab.ui.container.Tab
        Tab3             matlab.ui.container.Tab
        Label1           matlab.ui.control.Label
        grayImage        matlab.ui.control.Image
        MatchButton      matlab.ui.control.Button
        BalanceButton    matlab.ui.control.Button
        UIImage          matlab.ui.control.Image
        UIAxesHistogram  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % 原始图像
        ProcessedImage  % 均衡化后的图像
        GrayImage       % 灰度图像
    end
    
    methods (Access = private)
        
        function updateHistogram(app, img)
            % 如果是彩色图像，将其转换为灰度图像
            if size(img, 3) == 3
                img = app.RGB2GRAY(img);
            end
            
            % 计算灰度直方图
            % 创建一个包含256个元素的零向量，用于存储每个灰度值的出现次数
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 清除之前的直方图
            cla(app.UIAxesHistogram);  % 清除 Axes 上之前的内容
            
            % 手动绘制直方图
            bar(app.UIAxesHistogram, 0:255, histogramData, 'FaceColor', 'b');
            title(app.UIAxesHistogram, '灰度直方图');
            xlabel(app.UIAxesHistogram, '灰度值');
            ylabel(app.UIAxesHistogram, '像素数量');
            axis(app.UIAxesHistogram, 'tight');  % 紧缩坐标轴
        end


        function balancedImg = customHistogramEqualization(app, img)
            % 如果是彩色图像，将其转换为灰度图像
            if size(img, 3) == 3
                img = app.RGB2GRAY(img);
            end
            
            % 计算图像的直方图
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 计算累积分布函数（CDF）
            cdf = cumsum(histogramData) / (rows * cols);  % 归一化到 [0,1]
            
            % 创建新的均衡化图像
            balancedImg = uint8(zeros(rows, cols));  % 初始化均衡化后的图像
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j);  % 获取原图像的灰度值
                    % 映射到新的均衡化灰度值
                    balancedImg(i,j) = uint8(cdf(grayValue + 1) * 255);
                end
            end
        end
        
        function matchedImg = customHistogramMatching(app, sourceImg, targetImg)
            % 如果源图像和目标图像是彩色的，将其转换为灰度图像
            if size(sourceImg, 3) == 3
                sourceImg = app.RGB2GRAY(sourceImg);
            end
            if size(targetImg, 3) == 3
                targetImg = app.RGB2GRAY(targetImg);
            end
            
            % 获取图像的尺寸
            [rows, cols] = size(sourceImg);
            
            % 计算源图像的直方图
            sourceHist = zeros(1, 256);
            for i = 1:rows
                for j = 1:cols
                    grayValue = sourceImg(i,j) + 1;  % +1 因为灰度值从 0 开始
                    sourceHist(grayValue) = sourceHist(grayValue) + 1;
                end
            end
            
            % 计算目标图像的直方图
            targetHist = zeros(1, 256);
            for i = 1:rows
                for j = 1:cols
                    grayValue = targetImg(i,j) + 1;
                    targetHist(grayValue) = targetHist(grayValue) + 1;
                end
            end
            
            % 计算源图像的累积分布函数 (CDF)
            sourceCDF = cumsum(sourceHist) / (rows * cols);
            
            % 计算目标图像的累积分布函数 (CDF)
            targetCDF = cumsum(targetHist) / (rows * cols);
            
            % 对源图像的像素值进行映射
            matchedImg = uint8(zeros(rows, cols));
            for i = 1:rows
                for j = 1:cols
                    grayValue = sourceImg(i,j);
                    % 找到源图像像素的最小映射
                    [~, idx] = min(abs(sourceCDF(grayValue+1) - targetCDF));
                    matchedImg(i,j) = idx - 1;  % 映射后的像素值
                end
            end
            
        end
        
        function grayImg = RGB2GRAY(app,img)
            red = img(:,:,1);
            green= img(:,:,2);
            blue= img(:,:,3);
            % 按公式处理
            grayImg = red*0.299 + green*0.587 +blue* 0.114;
            grayImg = uint8(grayImg);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportImageMenu, Menu
        function ImportImageMenuSelected(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.gif', 'Image Files (*.jpg, *.png, *.jpeg, *.bmp, *.gif)'});
    
            if file ~= 0  % 如果用户选择了文件
                imgPath = fullfile(path, file);
                img = imread(imgPath);  % 读取图像
                
                % 显示图像，app.UIImage.ImageSource 需要是图像数组而不是路径
                app.UIImage.ImageSource = img;  % 将图像数据传递给 UIImage 组件
                
                % 如果图像是彩色的，转换为灰度图像
                if size(img, 3) == 3
                    img = app.RGB2GRAY(img);  % 转换为灰度图像
                end
                
                app.OriginalImage = img;  % 保存原始图像
                
                % 使用自定义函数更新灰度直方图
                updateHistogram(app, img);  % 调用自定义函数显示灰度直方图
                
                app.GrayImage = cat(3, img, img, img);  % 直接保存灰度图像
            
                % 将灰度图像显示在 GrayImage 组件上
                app.grayImage.ImageSource = app.GrayImage;  % 将灰度图像显示在 GrayImage 组件上
                app.Label1.Text = '灰度图像';
            else
                disp('No image selected');
            end
        end

        % Button pushed function: BalanceButton
        function BalanceButtonPushed(app, event)
            if isempty(app.OriginalImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入图像！', '图像未加载');
                return;
            end
            
            % 对图像进行均衡化
            balancedImg = histeq(app.OriginalImage);
            app.ProcessedImage = balancedImg;
            
            if size(balancedImg, 3) == 1  % 如果是灰度图像
                balancedImgRGB = cat(3, balancedImg, balancedImg, balancedImg);  % 将灰度图像转换为 RGB 图像
            else
                balancedImgRGB = balancedImg;  % 如果已经是彩色图像，则不需要转换
            end
            
            
            % 更新 UIImage 组件中的图像
            app.UIImage.ImageSource = balancedImgRGB;  % 显示均衡化后的图像（RGB 格式）
            
            % 使用自定义函数更新灰度直方图
            updateHistogram(app, balancedImg);  % 调用自定义函数显示均衡化后的灰度直方图
        end

        % Button pushed function: MatchButton
        function MatchButtonPushed(app, event)
            if isempty(app.OriginalImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入源图像！', '图像未加载');
                return;
            end
            
            % 使用自定义的直方图均衡化函数生成目标图像
            targetImg = customHistogramEqualization(app, app.OriginalImage);  % 均衡化后的图像
            
            % 使用自定义的直方图匹配函数
            matchedImg = customHistogramMatching(app, app.OriginalImage, targetImg);
            
            % 更新处理后的图像
            app.ProcessedImage = matchedImg;
            
            % 将匹配后的灰度图像转换为 RGB 图像（如果需要显示在 UIImage 组件中）
            matchedImgRGB = cat(3, matchedImg, matchedImg, matchedImg);  % 转换为 RGB 图像
            
            % 更新 UIImage 组件中的图像
            app.UIImage.ImageSource = matchedImgRGB;  % 显示匹配后的图像（RGB 格式）
            
            % 使用自定义函数更新灰度直方图
            updateHistogram(app, matchedImg);  % 调用自定义函数显示匹配后的灰度直方图
        end

        % Button pushed function: linearButton
        function linearButtonPushed(app, event)
            % 获取用户输入的增益 (a) 和偏移 (b)
%             a = str2double(strtrim(app.a.Value));  
%             b = str2double(strtrim(app.b.Value));  
            a = app.a.Value;
            b = app.b.Value;
            % 确保输入的值是有效的数字
            if isnan(a) || isnan(b)
                uialert(app.UIFigure, '请输入有效的增益和偏移值！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;
            
            % 将图像从 uint8 转为 double 类型，以便进行线性变换
            img = double(img);
            
            % 线性增强
            enhancedImg = a * img + b;
            
            % 确保增强后的图像像素值在有效范围 [0, 255]
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            % 更新灰度图像显示组件
            app.grayImage.ImageSource = enhancedImg;  % 更新 UI 显示
        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
             % 获取用户输入的增益常数 c
            c = app.c.Value; 
            
            % 检查 c 是否有效
            if isnan(c)
                uialert(app.UIFigure, '请输入有效的增益常数 c！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;  % 假设灰度图像已存在于 app.GrayImage 中
            
            % 确保图像是 double 类型，以便进行浮点运算
            img = double(img);
            
            % 对图像应用对数增强
            enhancedImg = c * log(1 + img);
            
            % 限制图像像素值在 0 到 255 之间，并转换回 uint8 类型
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            app.grayImage.ImageSource = enhancedImg;  % 刷新图像组件显示
        end

        % Button pushed function: indexButton
        function indexButtonPushed(app, event)
            
            alpha = app.index1.Value; 
            c = app.index2.Value; 
            
            % 检查 c 是否有效
            if isnan(c)||isnan(alpha)
                uialert(app.UIFigure, '请输入有效的增益常数 c！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;  % 假设灰度图像已存在于 app.GrayImage 中
            
            % 确保图像是 double 类型，以便进行浮点运算
            img = double(img);
            
           enhancedImg = c * (exp(alpha * img) - 1);
    
            % 限制图像像素值在 0 到 255 之间，并转换回 uint8 类`型
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            app.grayImage.ImageSource = enhancedImg;  % 刷新图像组件显示
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.ImportImageMenu = uimenu(app.Menu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.UIAxesHistogram = uiaxes(app.UIFigure);
            title(app.UIAxesHistogram, '灰度直方图')
            xlabel(app.UIAxesHistogram, '灰度值')
            ylabel(app.UIAxesHistogram, '像素数量')
            zlabel(app.UIAxesHistogram, 'Z')
            app.UIImage = uiimage(app.UIFigure);
            app.BalanceButton = uibutton(app.UIFigure, 'push');
            app.BalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceButtonPushed, true);
            app.BalanceButton.Text = '均衡化';
            app.MatchButton = uibutton(app.UIFigure, 'push');
            app.MatchButton.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.MatchButton.Text = '匹配';
            app.grayImage = uiimage(app.UIFigure);
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = {''; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '对比度增强';
            app.TabGroup1 = uitabgroup(app.Tab1);
            app.Tab11 = uitab(app.TabGroup1);
            app.Tab11.Title = '线性增强';
            app.linearF = uiimage(app.Tab11);
            app.linearF.ImageSource = '线性增强.png';
            app.aLabel = uilabel(app.Tab11);
            app.aLabel.Text = 'a =';
            app.a = uieditfield(app.Tab11, 'numeric');
            app.a.Value = 1;
            app.bLabel = uilabel(app.Tab11);
            app.bLabel.Text = 'b =';
            app.b = uieditfield(app.Tab11, 'numeric');
            app.linearButton = uibutton(app.Tab11, 'push');
            app.linearButton.ButtonPushedFcn = createCallbackFcn(app, @linearButtonPushed, true);
            app.linearButton.Text = '确认';
            app.Tab12 = uitab(app.TabGroup1);
            app.Tab12.Title = '对数增强';
            app.logF = uiimage(app.Tab12);
            app.logF.ImageSource = '对数增强.png';
            app.cLabel = uilabel(app.Tab12);
            app.cLabel.Text = 'c =';
            app.c = uieditfield(app.Tab12, 'numeric');
            app.c.Value = 1;
            app.logButton = uibutton(app.Tab12, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Text = '确认';
            app.Tab13 = uitab(app.TabGroup1);
            app.Tab13.Title = '指数增强';
            app.indexButton = uibutton(app.Tab13, 'push');
            app.indexButton.ButtonPushedFcn = createCallbackFcn(app, @indexButtonPushed, true);
            app.indexButton.Text = '确认';
            app.indexF = uiimage(app.Tab13);
            app.indexF.ImageSource = '指数增强.png';
            app.Label = uilabel(app.Tab13);
            app.Label.Text = 'α =';
            app.index1 = uieditfield(app.Tab13, 'numeric');
            app.index1.Value = 1;
            app.cLabel_3 = uilabel(app.Tab13);
            app.cLabel_3.Text = 'c =';
            app.index2 = uieditfield(app.Tab13, 'numeric');
            app.index2.Value = 1;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '图像变换';
            app.TabGroup3 = uitabgroup(app.Tab2);
            app.Tab21 = uitab(app.TabGroup3);
            app.Tab21.Title = '缩放变换';
            app.Tab22 = uitab(app.TabGroup3);
            app.Tab22.Title = '旋转变换';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end